classdef finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        InputValuesTab              matlab.ui.container.Tab
        DPoseSimulationButton       matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        FocallengthinxaxisTextArea  matlab.ui.control.TextArea
        FocallengthinxaxisTextAreaLabel  matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        FocallengthinyaxisTextArea  matlab.ui.control.TextArea
        YEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        FocallengthinyaxisTextAreaLabel  matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        Lamp                        matlab.ui.control.Lamp
        LampLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        %GUI properties
        cam;                    % Wemcam Object
        avgPosition;            % Average position of detected points
        points;                 % Detected points on the checkerboard
        originalStart;          % Original starting position
        x_cm;                   % X-coordinate in centimeters
        y_cm;                   % Y-coordinate in centimeters
        zCam_cm;                % Z-coordinate in centimeters from the camera
        acceptableErrorX;       % Acceptable error for X-coordinate
        acceptableErrorY;       % Acceptable error for Y-coordinate
        acceptableErrorZ;       % Acceptable error for Z-coordinate
        X = 0;                  % Flag for controlling the visual servoing loop
        initialDepth;           % Initial depth value
        acceptableError = 10;   % Acceptable error value
        originalZ;              % Original Z-coordinate value
        z_cm_values;            % Array to store Z-coordinate values in centimeters
        y_cm_values;            % Array to store Y-coordinate values in centimeters
        x_cm_values;            % Array to store X-coordinate values in centimeters
        z_values;               % Array to store Z-coordinate values
        images;                 % Image datastore for simulation
        squareSize;             % Size of the squares on the checkerboard
        worldPoints;            % World coordinates of checkerboard points
        imageSize;              % Size of the captured image
        imgPoints;              % Detected image points
        boardSize;              % Size of the checkerboard
        updateCounter;          % Counter for updating
        I;                      % Current image
        cameraParams;           % Camera parameters
        img;                    % Current image
        fx_Value;               % Focal length in the x-axis
        fy_Value;               % Focal length in the y-axis
        pic;                    % Snapshot from the webcam
        demo;                   % Placeholder for the main algorithm code
        

    end
    
    methods (Access = private)
        
        function zCalc(app)
        %% Camera Calibration Data and setting varaibles
            
            r = [27.5, 0];        % Define the vectors (r, s) and (t, u) based on the checkerboard dimensions
            s = [0, 27.5];
            t = 3.5;              % Define the scalar factor t (you may need to adjust this, (3.5 works for the phone,1.7 for the big board) )

            app.z_cm_values = [];                                
            app.x_cm_values = [];
            app.y_cm_values = [];

            a = app.points(1, :);                                                  % choosing a reference point
            prevZ = 0;

%% Testing for 3 Points anywhere on checkerboard
            for i = 2:size(app.points, 1)                                          % Find two points (b and c) that form a triangle with the reference point
                if ~isequal(app.points(i, :), a)
                    b = app.points(i, :);
                    break;
                end
            end
            
            for i = 2:size(app.points, 1)
                if ~isequal(app.points(i, :), a) && ~isequal(app.points(i, :), b)
                    c = app.points(i, :);
                    break;
                end
            end

            if isempty(b) || isempty(c)   % Verify that you have three non-collinear points                                           % Verify that you have three non-collinear points
                error('Unable to find three non-collinear points.');
            end
%% Calculating Z Value and converting X,Y,Z to cm values
            for i = 2:size(app.points, 1)
                x = app.points(i, 1);
                y = app.points(i, 2);
                                                                                
                z = 1 / t * dot(r, a) + dot(s, b) + t * dot(c, [x; y]);         % Calculate the z-value using the formula
                deltaX = app.avgPosition(1) - app.originalStart(1);             % Calculate the change in X and Y positions in pixels
                deltaY = app.avgPosition(2) - app.originalStart(2);
                deltaZ = z - prevZ;                                             % Calculate the change in Z from prevZ
                prevZ = z;                                                      % Update prevZ for the next iteration
                                                      
                if isempty(app.z_cm_values)                                     % Update the originalZ property if it's the first iteration
                    app.originalZ = z;
                end
                
                % Convert the z-value to centimeters using the calibration data
                app.zCam_cm = ((app.fx_Value * app.fy_Value) / deltaZ) / 10;                       % Divide by 10 to convert from cm to cm
                app.x_cm = ((app.fx_Value * deltaX) / app.fx_Value) / 10;                          % Convert pixel to cm for X-axis (assuming a square pixel)
                app.y_cm = ((app.fy_Value * deltaY) / app.fy_Value) / 10;                          % Convert pixel to cm for Y-axis (assuming a square pixel)
        
                app.z_cm_values = [app.z_cm_values; app.zCam_cm];
                app.x_cm_values = [app.x_cm_values; app.x_cm];
                app.y_cm_values = [app.y_cm_values; app.y_cm];
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

        %% main visual servoing code
        app.demo = hamish_IBVS;                     %property called demo to run main algorithm code within GUI
        app.X = 0;
        app.cam = webcam("Integrated Camera");      %Camera name dependent on the camera being used i.e. Integrated Camera/Surface Camera Front 
        app.img = snapshot(app.cam);
        
            while app.X == 0                        % Main while loop to run visual servoing

                if app.X == 1
                    break;
                end

                app.img = snapshot(app.cam);
                app.img = flip(app.img, 2);
                axis(app.UIAxes3, 'image');         % Ensure proper configuration of app.UIAxes3
                grayImg = rgb2gray(app.img);
        
                % Setting up checkerboard point detection, values display
                [imagePoints, ~] = detectCheckerboardPoints(grayImg);
                app.points = [];
                dispMessage = false;
        
                if ~isempty(imagePoints)
                    app.points = [app.points; imagePoints];
                    app.points = app.points(~any(isnan(app.points), 2), :);
                    app.avgPosition = mean(app.points);
        
                    if isempty(app.originalStart)
                        app.originalStart = app.avgPosition;
                    end
        
                    app.zCalc;

                    errorX = abs(app.x_cm);
                    errorY = abs(app.y_cm);
                    errorZ = abs(app.zCam_cm);
        
                    if all(errorX <= app.acceptableErrorX) && all(errorY <= app.acceptableErrorY) && all(errorZ <= app.acceptableErrorZ)
                        dispMessage = true;
                        overlayColor = 'green';
                    else
                        overlayColor = 'red';
                    end
       
                    
                    hold on
                    % Display guidance arrow from avgPosition to original Position
                    if ~any(isnan(app.avgPosition))
                        arrow = app.originalStart - app.avgPosition;
                        app.img = insertMarker(app.img, app.avgPosition, 'o', 'Color', overlayColor, 'Size', 5);
                        app.img = insertShape(app.img, 'Line', [app.avgPosition, app.avgPosition + arrow], 'Color', overlayColor, 'LineWidth', 2);
                    end


                   disp(['Position: X=', str2double(app.x_cm), ' cm, Y=', str2double(-app.y_cm)]);                                             % Display the average position
                   disp(['Depth From Camera (cm): ', str2double(app.zCam_cm)]);                                                              % Display the depth in centimeters                                                      % Display the depth in centimeters

                    if dispMessage
                        app.img = insertText(app.img, [20, 65], 'CAMERA LOCATED', 'FontSize', 16, 'BoxColor', 'w', 'TextColor', 'green');
                    end
        
                    % Display detected points
                    for i = 1 : length(app.points)
                        if mod(i, 2) == 0
                            app.img = insertMarker(app.img, app.points(i, :), 'o', 'Color', 'green', 'Size', 5);
                        else
                            app.img = insertMarker(app.img, app.points(i, :), 'o', 'Color', 'red', 'Size', 5);
                        end
                    end
        
                    % Show the image with overlays in app.UIAxes3
                    imshow(app.img, 'Parent', app.UIAxes3);
                    drawnow;
                end
            end
     
    % Handle stopping the loop when needed (e.g., add a stop condition).
    % Implement a way to exit the loop gracefully to avoid hanging your GUI.
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

                app.X = 1;
                clear app.cam; 
        
        end

        % Button pushed function: DPoseSimulationButton
        function StartButton_SIMULATION(app, event)
            app.X = 0;
            msgbox('LOADING SIMULATION')
            
            if app.X == 0
                app.images = imageDatastore(fullfile("C:\Users\sarah\OneDrive\Documents\2023 Spring\Sensors and Control for Mechatronic Systems\visual_servoing_project\Hamish_Test_Calib"));
        
                [app.imgPoints, app.boardSize] = detectCheckerboardPoints(app.images.Files);
        
                app.squareSize = 27.5;
                app.worldPoints = generateCheckerboardPoints(app.boardSize, app.squareSize);
        
                app.I = readimage(app.images, 1);
                app.imageSize = [size(app.I, 1), size(app.I, 2)];
                app.cameraParams = estimateCameraParameters(app.imgPoints, app.worldPoints, ...
                'ImageSize', app.imageSize);
                msgbox('HELLLLOOOOO')
        
                 app.cam = webcam("Integrated Camera");
        
                % % Create a figure for displaying the live camera feed
                % liveFeedFig = figure;
                % set(liveFeedFig, 'Name', 'Live Camera Feed', 'NumberTitle', 'Off');
                % 
                % Create a figure for displaying the simulated camera pose
                poseFig = figure;
                set(poseFig, 'Name', 'Simulated Camera Pose', 'NumberTitle', 'Off');
                
             
                while ishandle(poseFig)
                    % Capture an image from the webcam
                    app.pic = snapshot(app.cam);
                    grayImg = rgb2gray(app.pic);
                
                    % Close the webcam object
                    imOrig = grayImg;
                
                    % % Display live camera feed
                    % figure(liveFeedFig);
                    % imshow(app.img);
                    % title('Live Camera Feed');
                
                    [imagePoints, app.boardSize] = detectCheckerboardPoints(imOrig);
                
                    [rotationMatrix, translationVector] = extrinsics(...
                        imagePoints, app.worldPoints, app.cameraParams);
                
                    [orientation, location] = extrinsicsToCameraPose(rotationMatrix, ...
                        translationVector);
                
                        figure(poseFig);
                        app.img = plotCamera('Location', location, 'Orientation', orientation, 'Size', 20);
                         
                        title('Pose Plot');
                        hold on
                        app.img = pcshow([app.worldPoints, zeros(size(app.worldPoints, 1), 1)], ...
                                'VerticalAxisDir', 'down', 'MarkerSize', 40);
                       hold off
                       drawnow;
                end
            end

        end

        % Value changed function: FocallengthinxaxisTextArea
        function Fx_Value(app, event)
            app.fx_Value = app.FocallengthinxaxisTextArea.Value;
            
        end

        % Value changing function: FocallengthinxaxisTextArea
        function Fy_Value(app, event)
         
            
        end

        % Value changed function: FocallengthinyaxisTextArea
        function FocallengthinyaxisTextAreaValueChanged(app, event)
            app.fy_Value = app.FocallengthinyaxisTextArea.Value;
            
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
           app.XEditField.Value = app.x_cm;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputValuesTab = uitab(app.TabGroup);
            app.InputValuesTab.Title = 'Input Values';
            app.UIAxes3 = uiaxes(app.InputValuesTab);
            title(app.UIAxes3, 'Camera Output')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.XEditFieldLabel = uilabel(app.InputValuesTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.InputValuesTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.FocallengthinyaxisTextAreaLabel = uilabel(app.InputValuesTab);
            app.FocallengthinyaxisTextAreaLabel.Text = 'Focal length in y-axis';
            app.YEditFieldLabel = uilabel(app.InputValuesTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.InputValuesTab, 'numeric');
            app.FocallengthinyaxisTextArea = uitextarea(app.InputValuesTab);
            app.FocallengthinyaxisTextArea.ValueChangedFcn = createCallbackFcn(app, @FocallengthinyaxisTextAreaValueChanged, true);
            app.FocallengthinyaxisTextArea.Value = {'2603.63735'};
            app.ZEditFieldLabel = uilabel(app.InputValuesTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.InputValuesTab, 'numeric');
            app.StartButton = uibutton(app.InputValuesTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FocallengthinxaxisTextAreaLabel = uilabel(app.InputValuesTab);
            app.FocallengthinxaxisTextAreaLabel.Text = 'Focal length in x-axis';
            app.FocallengthinxaxisTextArea = uitextarea(app.InputValuesTab);
            app.FocallengthinxaxisTextArea.ValueChangedFcn = createCallbackFcn(app, @Fx_Value, true);
            app.FocallengthinxaxisTextArea.ValueChangingFcn = createCallbackFcn(app, @Fy_Value, true);
            app.FocallengthinxaxisTextArea.Value = {'2603.90493'};
            app.StopButton = uibutton(app.InputValuesTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DPoseSimulationButton = uibutton(app.InputValuesTab, 'push');
            app.DPoseSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartButton_SIMULATION, true);
            app.DPoseSimulationButton.Text = '3D Pose Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end