classdef finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LaptopCameraInputDropDown       matlab.ui.control.DropDown
        LaptopCameraInputDropDownLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InputValuesTab                  matlab.ui.container.Tab
        StopButton                      matlab.ui.control.Button
        FocallengthinxaxisTextArea      matlab.ui.control.TextArea
        FocallengthinxaxisTextAreaLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        FocallengthinyaxisTextArea      matlab.ui.control.TextArea
        FocallengthinyaxisTextAreaLabel  matlab.ui.control.Label
        ApplyCoordinatesButton          matlab.ui.control.Button
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        DSimulationTab                  matlab.ui.container.Tab
        StartButton_2                   matlab.ui.control.Button
        UIAxes3_2                       matlab.ui.control.UIAxes
        XTab                            matlab.ui.container.Tab
        PlotButton_2                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        YTab                            matlab.ui.container.Tab
        PlotButton_3                    matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        DepthTab                        matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        RotationTab                     matlab.ui.container.Tab
        Switch                          matlab.ui.control.Switch
        SwitchLabel                     matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        cam; % Description
        avgPosition;
        points;
        originalStart;
        x_cm;
        y_cm;
        zCam_cm;
        acceptableErrorX;
        acceptableErrorY;
        acceptableErrorZ;
        X;
        initialDepth;
        acceptableError = 10;
        originalZ;
        z_cm_values;
        y_cm_values;
        x_cm_values;
        z_values;    
        images;
        squareSize;
        worldPoints;
        imageSize;
        imgPoints;
        boardSize;
        updateCounter;
        I;
        cameraParams;
        img;

    end
    
    methods (Access = private)
        
        function zCalc(app)
        %% Camera Calibration Data and setting varaibles
            fx = 2603.90493;      % Focal length in x-axis
            fy = 2603.63735;      % Focal length in y-axis
            % cx = 1594.21893;    % Principal point in x-axis
            % cy = 949.73370 ;    % Principal point in y-axis
            % k1 =  0.16657;      % Radial distortion coefficient 1
            % k2 = -0.24939;      % Radial distortion coefficient 2
            % p1 = 0.00710;       % Tangential distortion coefficient 1
            % p2 = -0.00879;      % Tangential distortion coefficient 2
            % k3 = 0.0;           % Radial distortion coefficient 3
            
            r = [27.5, 0];        % Define the vectors (r, s) and (t, u) based on the checkerboard dimensions (THIS IS NO LONGER VALID WITH THE UPDATED CHECKERBOARD IM USING ON MY PHONE!)
            s = [0, 27.5];
            t = 3.5;              % Define the scalar factor t (you may need to adjust this, (3.5 works for the phone,1.7 for the big board) )

            app.z_cm_values = [];                                
            app.x_cm_values = [];
            app.y_cm_values = [];

            a = app.points(1, :);                                                  % choosing a reference point
            prevZ = 0;

%% Testing for 3 Points anywhere on checkerboard
            for i = 2:size(app.points, 1)                                          % Find two points (b and c) that form a triangle with the reference point
                if ~isequal(app.points(i, :), a)
                    b = app.points(i, :);
                    break;
                end
            end
            
            for i = 2:size(app.points, 1)
                if ~isequal(app.points(i, :), a) && ~isequal(app.points(i, :), b)
                    c = app.points(i, :);
                    break;
                end
            end

            if isempty(b) || isempty(c)                                              % Verify that you have three non-collinear points
                error('Unable to find three non-collinear points.');
            end
%% Calculating Z Value and converting X,Y,Z to cm values
            for i = 2:size(app.points, 1)
                x = app.points(i, 1);
                y = app.points(i, 2);
                                                                                
                z = 1 / t * dot(r, a) + dot(s, b) + t * dot(c, [x; y]);         % Calculate the z-value using the formula
                deltaX = app.avgPosition(1) - app.originalStart(1);           % Calculate the change in X and Y positions in pixels
                deltaY = app.avgPosition(2) - app.originalStart(2);
                deltaZ = z - prevZ;                                             % Calculate the change in Z from prevZ
                prevZ = z;                                                      % Update prevZ for the next iteration
                                                      
                if isempty(app.z_cm_values)                                    % Update the originalZ property if it's the first iteration
                    app.originalZ = z;
                end
                
                                                                                % Convert the z-value to centimeters using the calibration data
                app.zCam_cm = ((fx * fy) / deltaZ) / 10;                       % Divide by 10 to convert from cm to cm
                app.x_cm = ((fx * deltaX) / fx) / 10;                          % Convert pixel to cm for X-axis (assuming a square pixel)
                app.y_cm = ((fy * deltaY) / fy) / 10;                          % Convert pixel to cm for Y-axis (assuming a square pixel)
        
                app.z_cm_values = [app.z_cm_values; app.zCam_cm];
                app.x_cm_values = [app.x_cm_values; app.x_cm];
                app.y_cm_values = [app.y_cm_values; app.y_cm];
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

        %% main visual servoing code
        app.X = 0;
        app.cam = webcam("Surface Camera Front");
        app.img = snapshot(app.cam);

            while app.X == 0  % Main while loop to run visual servoing
                app.updateCounter = app.updateCounter + 1;

                app.app.img = snapshot(app.cam);
                app.img = flip(app.img, 2);
                axis(app.UIAxes3, 'image');  % Ensure proper configuration of app.UIAxes3
                grayImg = rgb2gray(app.img);
        
                % Setting up checkerboard point detection, values display
                [imagePoints, ~] = detectCheckerboardPoints(grayImg);
                app.points = [];
                dispMessage = false;
        
                if ~isempty(imagePoints)
                    app.points = [app.points; imagePoints];
                    app.points = app.points(~any(isnan(app.points), 2), :);
                    app.avgPosition = mean(app.points);
        
                    if isempty(app.originalStart)
                        app.originalStart = app.avgPosition;
                    end
        
                    app.zCalc;

                    errorX = abs(app.x_cm);
                    errorY = abs(app.y_cm);
                    errorZ = abs(app.zCam_cm);
        
                    if all(errorX <= app.acceptableErrorX) && all(errorY <= app.acceptableErrorY) && all(errorZ <= app.acceptableErrorZ)
                        dispMessage = true;
                        overlayColor = 'green';
                    else
                        overlayColor = 'red';
                    end
        
                    disp(['Position: X=', num2str(app.x_cm), ' cm, Y=', num2str(-app.y_cm)]);
                    disp(['Depth From Camera (cm): ', num2str(app.zCam_cm)]);
                    
                    hold on
                    % Display guidance arrow from avgPosition to original Position
                    if ~any(isnan(app.avgPosition))
                        arrow = app.originalStart - app.avgPosition;
                        app.img = insertMarker(app.img, app.avgPosition, 'o', 'Color', overlayColor, 'Size', 5);
                        app.img = insertShape(app.img, 'Line', [app.avgPosition, app.avgPosition + arrow], 'Color', overlayColor, 'LineWidth', 2);
                    end
        
                    % Display the position information
                    app.img = insertText(app.img, [20, 20], sprintf('Depth (cm): %.2f ', app.zCam_cm), 'FontSize', 12, 'BoxColor', 'w', 'TextColor', overlayColor);
                    app.img = insertText(app.img, [20, 40], sprintf('X: %.2f cm, Y: %.2f cm', app.x_cm, -app.y_cm), 'FontSize', 12, 'BoxColor', 'w', 'TextColor', overlayColor);
        
                    if dispMessage
                        app.img = insertText(app.img, [20, 65], 'CAMERA LOCATED', 'FontSize', 16, 'BoxColor', 'w', 'TextColor', 'green');
                    end
        
                    % Display detected points
                    for i = 1 : length(app.points)
                        if mod(i, 2) == 0
                            app.img = insertMarker(app.img, app.points(i, :), 'o', 'Color', 'green', 'Size', 5);
                        else
                            app.img = insertMarker(app.img, app.points(i, :), 'o', 'Color', 'red', 'Size', 5);
                        end
                    end
        
                    % Show the image with overlays in app.UIAxes3
                    imshow(app.img, 'Parent', app.UIAxes3);
                end
            end

    % Handle stopping the loop when needed (e.g., add a stop condition).
    % Implement a way to exit the loop gracefully to avoid hanging your GUI.
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.X = 1;
            app.cam.Disable = true;
        end

        % Button pushed function: StartButton_2
        function StartButton_SIMULATION(app, event)

        app.images = imageDatastore(fullfile("C:\Users\61459\OneDrive - UTS (1)\2023 sem 2\Sensors and Control\visual_servoing_project\Hamish_Test_Calib"));

        [app.imgPoints, app.boardSize] = detectCheckerboardPoints(app.images.Files);

        app.squareSize = 27.5;
        app.worldPoints = generateCheckerboardPoints(app.boardSize, app.squareSize);

        app.I = readimage(app.images, 1);
        app.imageSize = [size(app.I, 1), size(app.I, 2)];
        app.cameraParams = estimateCameraParameters(app.imgPoints, app.worldPoints, ...
        'ImageSize', app.imageSize);

            % img = snapshot(app.cam);
            % img = flip(img, 2);
            axis(app.UIAxes3_2, 'image'); 

            % liveFeedFig = figure;
            % set(liveFeedFig, 'Name', 'Live Camera Feed', 'NumberTitle', 'Off');
            % 
            % % Create a figure for displaying the simulated camera pose
            % poseFig = figure;
            % set(poseFig, 'Name', 'Simulated Camera Pose', 'NumberTitle', 'Off');
            % app.updateCounter = 0;
            
             while app.X == 0
                [imagePoints, ~] = detectCheckerboardPoints(imOrig);
            
                [rotationMatrix, translationVector] = extrinsics(...
                    imagePoints, app.worldPoints, app.cameraParams);
            
                [orientation, location] = extrinsicsToCameraPose(rotationMatrix, ...
                    translationVector);
            
                % Check if the counter reaches 5 to update the simulated camera pose
                if app.updateCounter == 5
                    % Display the simulated camera pose
                    imshow(app.img, 'Parent', app.UIAxes3_2);
                    plotCamera('Location', location, 'Orientation', orientation, 'Size', 20);
                    title('Pose Plot');
                    hold on
                    pcshow([app.worldPoints, zeros(size(app.worldPoints, 1), 1)], ...
                            'VerticalAxisDir', 'down', 'MarkerSize', 40);
                   hold off
            
                    % Reset the counter
                    app.updateCounter = 0;
                end
            
                % Increment the counter
                drawnow;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Camera', 'Image'};
            app.Switch.Value = 'Camera';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputValuesTab = uitab(app.TabGroup);
            app.InputValuesTab.Title = 'Input Values';
            app.UIAxes3 = uiaxes(app.InputValuesTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.XEditFieldLabel = uilabel(app.InputValuesTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.InputValuesTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.InputValuesTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.InputValuesTab, 'numeric');
            app.ApplyCoordinatesButton = uibutton(app.InputValuesTab, 'push');
            app.ApplyCoordinatesButton.Text = 'Apply Coordinates';
            app.FocallengthinyaxisTextAreaLabel = uilabel(app.InputValuesTab);
            app.FocallengthinyaxisTextAreaLabel.Text = 'Focal length in y-axis';
            app.FocallengthinyaxisTextArea = uitextarea(app.InputValuesTab);
            app.FocallengthinyaxisTextArea.Value = {'2603.63735'};
            app.ZEditFieldLabel = uilabel(app.InputValuesTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.InputValuesTab, 'numeric');
            app.StartButton = uibutton(app.InputValuesTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FocallengthinxaxisTextAreaLabel = uilabel(app.InputValuesTab);
            app.FocallengthinxaxisTextAreaLabel.Text = 'Focal length in x-axis';
            app.FocallengthinxaxisTextArea = uitextarea(app.InputValuesTab);
            app.FocallengthinxaxisTextArea.Value = {'2603.90493'};
            app.StopButton = uibutton(app.InputValuesTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DSimulationTab = uitab(app.TabGroup);
            app.DSimulationTab.Title = '3D Simulation';
            app.UIAxes3_2 = uiaxes(app.DSimulationTab);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.StartButton_2 = uibutton(app.DSimulationTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_SIMULATION, true);
            app.StartButton_2.Text = 'Start';
            app.XTab = uitab(app.TabGroup);
            app.XTab.Title = 'X';
            app.UIAxes = uiaxes(app.XTab);
            title(app.UIAxes, 'X Distance from Target')
            xlabel(app.UIAxes, 'Time (Seconds)')
            ylabel(app.UIAxes, 'X (cm)')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton_2 = uibutton(app.XTab, 'push');
            app.PlotButton_2.Text = 'Plot';
            app.YTab = uitab(app.TabGroup);
            app.YTab.Title = 'Y';
            app.UIAxes_2 = uiaxes(app.YTab);
            title(app.UIAxes_2, 'Y Distance from Target')
            xlabel(app.UIAxes_2, 'Time (Seconds)')
            ylabel(app.UIAxes_2, 'Y (cm)')
            zlabel(app.UIAxes_2, 'Z')
            app.PlotButton_3 = uibutton(app.YTab, 'push');
            app.PlotButton_3.Text = 'Plot';
            app.DepthTab = uitab(app.TabGroup);
            app.DepthTab.Title = 'Depth';
            app.UIAxes2 = uiaxes(app.DepthTab);
            title(app.UIAxes2, 'Depth Distance from Target')
            xlabel(app.UIAxes2, 'Time (Seconds)')
            ylabel(app.UIAxes2, 'Z (cm)')
            zlabel(app.UIAxes2, 'Z')
            app.PlotButton = uibutton(app.DepthTab, 'push');
            app.PlotButton.Text = 'Plot';
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.LaptopCameraInputDropDownLabel = uilabel(app.UIFigure);
            app.LaptopCameraInputDropDownLabel.Text = 'Laptop Camera Input';
            app.LaptopCameraInputDropDown = uidropdown(app.UIFigure);
            app.LaptopCameraInputDropDown.Items = {'Cameron', 'Hamish', 'Sarah'};
            app.LaptopCameraInputDropDown.Value = 'Cameron';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end